classdef Speaker_Rec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InputFileLabel             matlab.ui.control.Label
        MatchedSpeakerLabel        matlab.ui.control.Label
        SearchButton               matlab.ui.control.Button
        PlayInputButton            matlab.ui.control.Button
        PlayOutputButton           matlab.ui.control.Button
        SpeakerIDEditFieldLabel    matlab.ui.control.Label
        SpeakerIDEditField         matlab.ui.control.NumericEditField
        SpeakerIDEditField_2Label  matlab.ui.control.Label
        SpeakerIDEditField_2       matlab.ui.control.NumericEditField
        SpeakerRecognitionLabel    matlab.ui.control.Label
    end


    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            inID = app.SpeakerIDEditField.Value; %Insert speaker ID
            outID = voiceRec(inID); %Find output speaker
            app.SpeakerIDEditField_2.Value = outID;
        end

        % Button pushed function: PlayInputButton
        function PlayInputButtonPushed(app, event)
            inID = app.SpeakerIDEditField.Value; %Insert speaker ID
            if inID > 0
                fileI=sprintf('t%d.wav',inID);
                [dataI,FsI]=audioread(fileI);
                sound(dataI,FsI);
            end
        end

        % Button pushed function: PlayOutputButton
        function PlayOutputButtonPushed(app, event)
            inID = app.SpeakerIDEditField.Value; %Insert speaker ID
            outID = voiceRec(inID); %Find output speaker
            
            if outID ~= 0
                fileO=sprintf('s%d.wav',outID);
                [dataO,FsO]=audioread(fileO);
                sound(dataO,FsO);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputFileLabel = uilabel(app.UIFigure);
            app.InputFileLabel.Text = {'Input File'; ''};
            app.MatchedSpeakerLabel = uilabel(app.UIFigure);
            app.MatchedSpeakerLabel.Text = 'Matched Speaker';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.PlayInputButton = uibutton(app.UIFigure, 'push');
            app.PlayInputButton.ButtonPushedFcn = createCallbackFcn(app, @PlayInputButtonPushed, true);
            app.PlayInputButton.Text = {'Play Input'; ''};
            app.PlayOutputButton = uibutton(app.UIFigure, 'push');
            app.PlayOutputButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputButtonPushed, true);
            app.PlayOutputButton.Text = {'Play Output'; ''};
            app.SpeakerIDEditFieldLabel = uilabel(app.UIFigure);
            app.SpeakerIDEditFieldLabel.Text = 'Speaker ID:';
            app.SpeakerIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeakerIDEditField_2Label = uilabel(app.UIFigure);
            app.SpeakerIDEditField_2Label.Text = 'Speaker ID:';
            app.SpeakerIDEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SpeakerRecognitionLabel = uilabel(app.UIFigure);
            app.SpeakerRecognitionLabel.Text = {'Speaker Recognition'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Speaker_Rec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end